classdef faceRecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        UIAxes1                    matlab.ui.control.UIAxes
        Panel2                     matlab.ui.container.Panel
        UIAxes2                    matlab.ui.control.UIAxes
        TrainPanel                 matlab.ui.container.Panel
        TrainButton                matlab.ui.control.Button
        SnhTrainEditFieldLabel     matlab.ui.control.Label
        NumTrainImg                matlab.ui.control.NumericEditField
        SEigenValueEditFieldLabel  matlab.ui.control.Label
        NumEigenVal                matlab.ui.control.NumericEditField
        TestPanel                  matlab.ui.container.Panel
        ChonAnh                    matlab.ui.control.Button
        NhanDang                   matlab.ui.control.Button
        XoaAnh                     matlab.ui.control.Button
        Clear                      matlab.ui.control.Button
        Panel3                     matlab.ui.container.Panel
        HTnLabel                   matlab.ui.control.Label
        HoTen                      matlab.ui.control.EditField
        NmSinhLabel                matlab.ui.control.Label
        NamSinh                    matlab.ui.control.EditField
        QuQunLabel                 matlab.ui.control.Label
        QueQuan                    matlab.ui.control.EditField
        TextAreaLabel              matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        M % Description
        N % Description
        m % Description
        Ppca % Description
        T % Description
        img % Description
        resultimg % Description
        trainpath % Description
        testpath % Description
         % Description
        EigenValue % Description
        NumTrain % Description
    end
    
    methods (Access = private)
        
        function clearImage(h)
            axesHandlesToChildObjects = findobj(h, 'Type', 'image');
            if ~isempty(axesHandlesToChildObjects)
                delete(axesHandlesToChildObjects);
            end
                return; % from ClearImagesFromAxes
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.trainpath = "F:\LVTN\code\FaceRecogition\TrainDB\";
           app.testpath  = "F:\LVTN\code\FaceRecogition\TestDB\";
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
           
            
            n = app.NumTrain;
            L = app.EigenValue;
            app.M=100; app.N=90; %Kich thuoc yeu cau cua anh
           
            X=zeros(n,(app.M*app.N)); %khoi tao ma tran X ban dau
            app.T=zeros(n,L); %Khoi tao ma tran T trong ko gian PCA
            app.TextArea.Value = [string(app.TextArea.Value);"Waiting for training each file in train folder"];
            for count=1:n
                file = sprintf('%s%d.jpg', app.trainpath, count);
                app.TextArea.Value = [string(app.TextArea.Value);"Create train each image in train folder:";file];

                
                img = imread(file); 

                FaceDetect = vision.CascadeObjectDetector; 
                FaceDetect.MergeThreshold = 7 ;
                BB = step(FaceDetect, img); 
             
                for i = 1 : size(BB, 1) 
                  j = imcrop(img, BB(i, :)); 

                end
                

                j=rgb2gray(j); %chuyen tu anh mau sang anh xam
                j=imresize(j,[app.M,app.N]); %chinh lai kich thuoc chuan cho anh
                X(count,:)=reshape(j,[1,app.M*app.N]); %chuyen anh ve dang 1D la 1xMN
            end
            Xb=X; %copy lai anh
            app.m=mean(X); %tinh gia tri trung binh cua cac anh
            for i=1:n
                X(i,:)=X(i,:)-app.m; %tinh phuong sai tu anh 1D
            end
            Q = (X'*X)/(n-1); %tim ma tran covariance
            [Evecm,Evalm] = eig(Q); %tinh gia tri eigen va vector eigen cua ma tran cov Q
            Eval = diag(Evalm); %dua cac gia tri eigen vo duong cheo ma tran
            [Evalsorted,Index]=sort(Eval,'descend'); %lam giam so cot eigen theo chieu giam dan
            Evecsorted=Evecm(:,Index);
            app.Ppca = Evecsorted(:,1:L); %ma tran pca sau khi giam so chieu
            
            for i=1:n
                app.T(i,:) = (Xb(i,:)-app.m)*app.Ppca; %chieu moi anh len PCA space
            end
        end

        % Button pushed function: ChonAnh
        function ChonAnhButtonPushed(app, event)
            
            
            [filename,pathname]=uigetfile('*.*','Chon anh dau vao');
          

            filewithpath=strcat(pathname,filename);
%             drawnow;
%             figure(app.UIFigure);
            
            app.img=imread(filewithpath);
           
            img = app.img; 
            
            FaceDetect = vision.CascadeObjectDetector; 
            FaceDetect.MergeThreshold = 7 ;
            BB = step(FaceDetect, img); 

            for i = 1 : size(BB, 1) 
              J = imcrop(img, BB(i, :)); 

            end  
            
         
            imshow(J,'parent',app.UIAxes1);
           
        end

        % Button pushed function: NhanDang
        function NhanDangButtonPushed(app, event)
%             M=app.M;
%             N=app.N;
         
            img = app.img; 

            FaceDetect = vision.CascadeObjectDetector; 
            FaceDetect.MergeThreshold = 7 ;
            BB = step(FaceDetect, img); 


            for i = 1 : size(BB, 1) 
              j = imcrop(img, BB(i, :)); 

            end


            n=app.NumTrain;
            j=rgb2gray(j);
            j=imresize(j,[app.M,app.N]);
            j=double(reshape(j,[1,app.M*app.N]));
            imgpca=(j-app.m)*app.Ppca; %chieu anh goc ban dau len ko gian PCA
            distarray = zeros(n,1); %khoi tao mang cho viec so sanh
            for i=1:n
                distarray(i)=sum(abs(app.T(i,:)-imgpca)); %tim khoang cach giua anh train va anh dau vao
                
                
            end
%             min_dist = min(distarray);
%             max_dist = max(distarray);
            flag = 0;
            for i=1:n
%                 distarray_1(i)=(distarray(i)-min_dist)/(max_dist-min_dist);

                file = sprintf('%d', distarray(i));
                app.TextArea.Value = [string(app.TextArea.Value);i;"Khoang cach giua test và train:";file];
                
                if distarray(i)<3.5e+03
                    
                    
                    a = sprintf("Accept this person");
                    app.TextArea.Value = [string(app.TextArea.Value);a];
                    flag = flag+1;
                else
                    a = sprintf("Reject this person");
                    app.TextArea.Value = [string(app.TextArea.Value);a];
                end
            end
            
            [result,indx]=min(distarray); %tim duoc anh match tu khoang cach nho nhat
                resultimg = imread(sprintf("%d.jpg",indx));
                imgGrayTrain = rgb2gray(resultimg);
            
             if indx <= 4 & flag == 1
                 
                detectorFace=vision.CascadeObjectDetector();
                boundingbox=step(detectorFace,imgGrayTrain);
                detpic2=insertObjectAnnotation(resultimg,'Rectangle',boundingbox,'Le Tien Dat');
                imshow(detpic2,'parent',app.UIAxes2);
                title(app.UIAxes2,'Anh sau khi nhan dang');
%             else 
%                 detectorFace=vision.CascadeObjectDetector();
%                 boundingbox=step(detectorFace,imgGrayTrain);
%                 detpic2=insertObjectAnnotation(resultimg,'Rectangle',boundingbox,'Unknown');
%                 imshow(detpic2,'parent',app.UIAxes2);
%                 title(app.UIAxes2,'Anh sau khi nhan dang');
             else
                pic = imread(sprintf('%s%d.jpg',app.trainpath,5));
                imshow(pic,'parent',app.UIAxes2);
                title(app.UIAxes2,'Anh sau khi nhan dang');
            end
%             app.resultimg = imread(sprintf('%s%d.jpg',app.trainpath,indx)); %cho ra duoc anh match tot nhat
%             imshow(app.resultimg,'parent',app.UIAxes2);
            
        end

        % Size changed function: TrainPanel
        function TrainPanelSizeChanged(app, event)
          
        end

        % Button pushed function: XoaAnh
        function XoaAnhButtonPushed(app, event)
%             UIFigureCloseRequest();
        end

        % Value changed function: NumTrainImg
        function NumTrainImgValueChanged(app, event)
            value = app.NumTrainImg.Value;
            app.NumTrain = value;
        end

        % Value changed function: NumEigenVal
        function NumEigenValValueChanged(app, event)
            value = app.NumEigenVal.Value;
            app.EigenValue = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
   
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
              app.TextArea.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes1 = uiaxes(app.Panel);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.UIAxes2 = uiaxes(app.Panel2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.TrainPanel = uipanel(app.UIFigure);
            app.TrainPanel.Title = 'Train';
            app.TrainPanel.SizeChangedFcn = createCallbackFcn(app, @TrainPanelSizeChanged, true);
            app.TrainButton = uibutton(app.TrainPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.SnhTrainEditFieldLabel = uilabel(app.TrainPanel);
            app.SnhTrainEditFieldLabel.Text = 'Số Ảnh Train';
            app.NumTrainImg = uieditfield(app.TrainPanel, 'numeric');
            app.NumTrainImg.ValueChangedFcn = createCallbackFcn(app, @NumTrainImgValueChanged, true);
            app.SEigenValueEditFieldLabel = uilabel(app.TrainPanel);
            app.SEigenValueEditFieldLabel.Text = 'Số Eigen Value';
            app.NumEigenVal = uieditfield(app.TrainPanel, 'numeric');
            app.NumEigenVal.ValueChangedFcn = createCallbackFcn(app, @NumEigenValValueChanged, true);
            app.TestPanel = uipanel(app.UIFigure);
            app.TestPanel.Title = 'Test';
            app.ChonAnh = uibutton(app.TestPanel, 'push');
            app.ChonAnh.ButtonPushedFcn = createCallbackFcn(app, @ChonAnhButtonPushed, true);
            app.ChonAnh.Text = 'Chọn Ảnh';
            app.NhanDang = uibutton(app.TestPanel, 'push');
            app.NhanDang.ButtonPushedFcn = createCallbackFcn(app, @NhanDangButtonPushed, true);
            app.NhanDang.Text = 'Nhận Dạng';
            app.XoaAnh = uibutton(app.TestPanel, 'push');
            app.XoaAnh.ButtonPushedFcn = createCallbackFcn(app, @XoaAnhButtonPushed, true);
            app.XoaAnh.Text = 'Xóa Ảnh';
            app.Clear = uibutton(app.TestPanel, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'Clear';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Panel3';
            app.HTnLabel = uilabel(app.Panel3);
            app.HTnLabel.Text = 'Họ Tên:';
            app.HoTen = uieditfield(app.Panel3, 'text');
            app.NmSinhLabel = uilabel(app.Panel3);
            app.NmSinhLabel.Text = 'Năm Sinh:';
            app.NamSinh = uieditfield(app.Panel3, 'text');
            app.QuQunLabel = uilabel(app.Panel3);
            app.QuQunLabel.Text = 'Quê Quán:';
            app.QueQuan = uieditfield(app.Panel3, 'text');
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = {''; ''};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = faceRecognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end